classdef qCLS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLSWindow                       matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StimulusTypeDropDown            matlab.ui.control.DropDown
        StimulusTypeDropDownLabel       matlab.ui.control.Label
        TestEarDropDown                 matlab.ui.control.DropDown
        TestEarDropDownLabel            matlab.ui.control.Label
        CalibrateButton                 matlab.ui.control.Button
        StimulusBandwidthDropDown       matlab.ui.control.DropDown
        StimulusBandwidthDropDownLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        subjectNotification             matlab.ui.control.Button
        text2                           matlab.ui.control.Label
        CU0_pushbutton                  matlab.ui.control.Button
        CU5_pushbutton                  matlab.ui.control.Button
        CU10_pushbutton                 matlab.ui.control.Button
        CU15_pushbutton                 matlab.ui.control.Button
        CU20_pushbutton                 matlab.ui.control.Button
        CU25_pushbutton                 matlab.ui.control.Button
        CU30_pushbutton                 matlab.ui.control.Button
        CU35_pushbutton                 matlab.ui.control.Button
        CU40_pushbutton                 matlab.ui.control.Button
        CU45_pushbutton                 matlab.ui.control.Button
        CU50_pushbutton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        testdata = struct('numTrials',100,...   % total number of trials
                'samplingRate',48000,...
                'SPLref',1.1219e-6,...              % from calibration, based on 1 kHz
                'SPL_Correction',0,...
                'subjectID',[],...
                'dr',[],...
                'qcls_par',[],...
                'qcls',[],...     % the qcls object that stores all the information about the adaptive track
                'testear',[],...
                'testcond',[],...
                'bandwidth',[],...
                'Freq_list',[250 500 750 1000 1500 2000 3000 4000 6000],...
                'sessionBegin',0,...
                'Stage',0,...       % Stage 1 is used to obtain estimates of the dynamic range at 1 kHz, Stage 2 is the adaptive procedure
                'xnext0',[4 60],... % The stimulus presented on the first trial, the first element is the frequency idx 4-- 1kHz, the second element is the level in SPL
                'TrialNum',0,...
                'StartTime',0,...
                'EndTime',0);
    end
    
    methods (Access = private)
        
        function Enable_CLSbuttons(app,state)
            set(app.CU0_pushbutton,'Enable',state)
            set(app.CU5_pushbutton,'Enable',state)
            set(app.CU10_pushbutton,'Enable',state)
            set(app.CU15_pushbutton,'Enable',state)
            set(app.CU20_pushbutton,'Enable',state)
            set(app.CU25_pushbutton,'Enable',state)
            set(app.CU30_pushbutton,'Enable',state)
            set(app.CU35_pushbutton,'Enable',state)
            set(app.CU40_pushbutton,'Enable',state)
            set(app.CU45_pushbutton,'Enable',state)
            set(app.CU50_pushbutton,'Enable',state)
            tic
            return          
        end


        function adaptive_track_qcls(app,r)
            t = toc; % response time
            if (app.testdata.TrialNum < app.testdata.numTrials) % repeat
                app.testdata.TrialNum = app.testdata.TrialNum + 1;

                % Find the next stimulus
                if app.testdata.Stage == 1
                    app.testdata.qcls.update(r, t, [4 app.testdata.dr.xnext]); % update the qcls object using the stimulus and response from the last trial
                    app.testdata.dr.update(r);          % update the dr object using the response from the last trial
                    
                    if app.testdata.dr.isDone == 1
                        app.testdata.Stage = app.testdata.Stage + 1;
                        lev_lim = app.testdata.dr.getDR();
                        app.testdata.qcls.par.x_lim(2,2) = lev_lim(2);
                        xnext = app.testdata.qcls.xnext;
                    else
                        xnext = [4 app.testdata.dr.xnext];
                    end
            
                elseif app.testdata.Stage == 2
                    app.testdata.qcls.update(r, t);
                    xnext = app.testdata.qcls.xnext;
                    if xnext(1) > 8     % clipping/safety limit at high frequencies for ER3
                        xnext(2) = min(xnext(2),97);
                    end

                end
                
                % Proceed with a trial of testing using the selected
                % stimulus
                fprintf('%5.0f %5.0f %5.1f\n',xnext,t)
                playback(app, xnext);
                
                % % plot the current estimate
                % [~, idx] = max(app.testdata.qcls.Lmodels);
                % phi = app.testdata.qcls.models(idx).phi;
                % alpha_est = app.testdata.qcls.calc_alpha(1:app.testdata.qcls.par.Nfreqs, app.testdata.qcls.models(idx).kfreqs, phi);
                % 
                % plot(app.UIAxes, alpha_est','linewidth',2);hold on;
                % text(app.UIAxes, app.testdata.qcls.x(1:end,1), app.testdata.qcls.x(1:end,2), cellstr(num2str(app.testdata.qcls.r)));
                % scatter(app.UIAxes, xnext(1), xnext(2),200,'r','s','fill');
                % hold off;
                % axis([1 10 0 120]);
                % xlabel(app.UIAxes, 'Frequency Band');
                % ylabel(app.UIAxes, 'Sound Pressure Level (dB SPL)');
                % title(app.UIAxes, ['Trial: ' num2str(app.testdata.TrialNum)]);
                % drawnow;
            else
                Enable_CLSbuttons(app,'off');
                app.subjectNotification.BackgroundColor = 'white';
                app.subjectNotification.Text = ' ';
                app.text2.Text = ' ';

                % Save the data
                timestamp = datetime('now');
                app.testdata.EndTime= string(timestamp);
                testdata = app.testdata; %#ok<ADPROPLC>
                save(['data/', app.testdata.subjectID, '_', char(timestamp,'MMddyyyyHHmm'), '.mat'],'testdata');

                % Activate all buttons and menus in the left panel
                app.RunButton.Enable = 'on';
                app.CalibrateButton.Enable = 'on';
                app.SubjectIDEditField.Enable = 'on';
                app.TestEarDropDown.Enable = 'on';
                app.StimulusBandwidthDropDown.Enable = 'on';
            end
        end
        
        function playback(app, stim)
                
            app.subjectNotification.BackgroundColor = 'red';
            app.subjectNotification.Text = 'Please Wait';

            % Stimulus parameter/index
            stimLev = stim(2);
            Freq_idx = stim(1); % discrete frequency based on the frequency list
            
            if app.testdata.testcond == 1
                stimType='tone';
            else
                stimType=app.testdata.stimtype;
            end
            stimDur=1000;
            % Stimulus frequency
            Freq_list = app.testdata.Freq_list;
            stimFreq=2^interp1(1:length(app.testdata.Freq_list),log2(Freq_list),Freq_idx);
            % Convert stimulus bandwidth in Hz
            if app.testdata.testcond == 1        % pure tone
                stimBW = 0;
            elseif app.testdata.testcond == 2       % quarter octave
                stimBW = stimFreq*(2.^(0.25/2) - 2.^(-0.25/2));
            elseif app.testdata.testcond == 3       % half octave
                stimBW = stimFreq*(2.^(0.5/2) - 2.^(-0.5/2));
            elseif app.testdata.testcond == 4       % one octave
                stimBW = stimFreq*(2.^(0.75/2) - 2.^(-0.75/2));
            elseif app.testdata.testcond == 5       % one octave
                stimBW = stimFreq*(2.^(1/2) - 2.^(-1/2));
            elseif app.testdata.testcond == 6       % 100 Hz
                stimBW = 100;
            elseif app.testdata.testcond == 7       % 400 Hz
                stimBW = 400;
            end
            sr=app.testdata.samplingRate;
            
            % Generate the stimulus
            [x,~,SPL_Correction]=StimGenerator(stimType,stimDur,stimFreq,stimBW,sr);
            
            % Set SPL
            SPLref = app.testdata.SPLref;
            stimLev = stimLev+SPL_Correction;
            Lcs = 20.*log10(rms(x)/SPLref);
            y = x.*10.^((stimLev  - Lcs)/20);

            if app.testdata.testear == 1
                z=zeros(size(y));
                y1 = [y, z];
            elseif app.testdata.testear == 2
                z=zeros(size(y));
                y1 = [z, y];
            else
                y1 = [y, y];
            end
            
            % Visual indicator for the stimulus
            Enable_CLSbuttons(app,'off');
            app.subjectNotification.BackgroundColor = 'red';
            app.subjectNotification.Text = 'Please Listen';
            
            % keyboard;

            NBITS=24;
            p = audioplayer(y1,sr,NBITS);
            % playblocking(p)   % playblocking used to work fine, but now causes an intermittent PortAudio Error under version 9.2.0.538062 (R2017a).
            
            play(p, [1 get(p,'TotalSamples')])
            pause(get(p,'TotalSamples')/get(p,'SampleRate')) % pause to allow playback! I don't know why I need to pause, but the code does not work when I don't pause.
            
            % Visual indicator for the response
            % if (~app.testdata.sessionBegin)
                Enable_CLSbuttons(app, 'on');
                app.subjectNotification.BackgroundColor = 'green';
                app.subjectNotification.Text = 'Respond';
                app.text2.Text = [num2str(app.testdata.TrialNum),'/',num2str(app.testdata.numTrials)];
            % end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CLS_OpeningFcn(app, varargin)
            % --- Executes just before CLS is made visible.
            rng('shuffle');
            
            % Ensure that the app appears on screen when run
            movegui(app.CLSWindow, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CLS (see VARARGIN)
            
            % Choose default command line output for CLS
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            Enable_CLSbuttons(app,'off');
        end

        % Button pushed function: CU0_pushbutton, CU10_pushbutton, 
        % ...and 9 other components
        function RespButton_Callback(app, event)
            % --- Executes on button press in CU50_pushbutton.
            
            % Get the response
            button_pressed = event.Source.Tag;
            switch button_pressed
                case 'CU50_pushbutton'
                    r = 11;
                case 'CU45_pushbutton'
                    r = 10;
                case 'CU40_pushbutton'
                    r = 9;
                case 'CU35_pushbutton'
                    r = 8;
                case 'CU30_pushbutton'
                    r = 7;
                case 'CU25_pushbutton'
                    r = 6;
                case 'CU20_pushbutton'
                    r = 5;
                case 'CU15_pushbutton'
                    r = 4;
                case 'CU10_pushbutton'
                    r = 3;
                case 'CU5_pushbutton'
                    r = 2;
                case 'CU0_pushbutton'
                    r = 1;
            end
            adaptive_track_qcls(app, r);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Begin the test and disable all buttons and menus in the left panel
            app.RunButton.Enable = 'off';
            app.CalibrateButton.Enable = 'off';
            app.SubjectIDEditField.Enable = 'off';
            app.TestEarDropDown.Enable = 'off';
            app.StimulusBandwidthDropDown.Enable = 'off';
            
            addpath qCLS_core/
            addpath data/
        
            % Initialize the rocedure
            app.testdata.dr = dynamic_range();          % initialize the first stage of the procedure to estimate the dynamic range
            app.testdata.qcls_par = qCLS_config();    % parameters for the qCLS procedure
            % qcls = MFCLS(app.testdata.qcls_par);   % uniform procedure
            app.testdata.qcls = MFCLS2(app.testdata.qcls_par);   % iso-phon procedure
            % qcls = entropyCLS(app.testdata.qcls_par); % entropy procedure
            
            % Get the subject ID
            app.testdata.subjectID = app.SubjectIDEditField.Value;
            % Get the test ear
            testear_str = app.TestEarDropDown.Value;
            switch testear_str
                case 'Left'
                    testear = 1;
                case 'Right'
                    testear = 2;
                case 'Both'
                    testear = 3;
            end
            app.testdata.testear = testear;
            % Get the stimulus type and stimulus bandwidth
            cond_str = app.StimulusTypeDropDown.Value;
            switch cond_str
                case 'Noise'
                    stimtype = 'noise';
                case 'AM'
                    stimtype = 'AM';
            end
            % Get the test condition
            cond_str = app.StimulusBandwidthDropDown.Value;
            switch cond_str
                case 'tone'
                    testcond= 1;
                case '1/4 Oct'
                    testcond = 2;
                case '1/2 Oct'
                    testcond = 3;
                case '3/4 Oct'
                    testcond = 4;
                case '1 Oct'
                    testcond = 5;
                case '100 Hz'
                    testcond = 6;
                case '400 Hz'
                    testcond = 7;
            end
            app.testdata.stimtype = stimtype;
            app.testdata.testcond = testcond;
            app.testdata.sessionBegin = 1;
            app.testdata.Stage = 1;
            app.testdata.TrialNum = 0;
            app.testdata.StartTime = 0;
            app.testdata.EndTime = 0;
            
            % Make sure that the participant is ready
            h=msgbox('Click OK when ready...','Test begin','Warn','non-modal');
            set(h,'Color',[0.937 0.867 0.867]);
            waitfor(h);
            % Bring the main window to the front
            app.CLSWindow.Visible = 'off';
            app.CLSWindow.Visible = 'on';

            % Starting the clock
            timestamp = datetime('now');
            app.testdata.StartTime= string(timestamp);

            % Play the stimulus for the first trial
            playback(app, app.testdata.xnext0);

        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)

            addpath qCLS_core/
            addpath data/
            if app.testdata.sessionBegin == 0
                calib_lev = 80;     % 80 dB SPL calibration tone
                SPLref = app.testdata.SPLref;
                
                stimType = 'tone';
                stimDur = 5000;
                stimFreq = 1000;
                stimBW = 0;
                sr = app.testdata.samplingRate;
                
                [x,~,SPL_Correction]=StimGenerator(stimType,stimDur,stimFreq,stimBW,sr);
                
                %Apply SPL correction
                Lin2=calib_lev+SPL_Correction;
                
                % set SPL
                Lcs = 20.*log10(rms(x)/SPLref);
                y = x.*10.^((Lin2 - Lcs)/20);
                y1 = [y, y];        % output to both channels
                
                % audio playback
                NBITS=24;
                p = audioplayer(y1,sr,NBITS);
                app.RunButton.Enable = 'off';
                app.CalibrateButton.Text = 'Playing 1kHz 80dB SPL Tone';
                playblocking(p);
                app.CalibrateButton.Text = 'Calibrate';
                app.RunButton.Enable = 'on';

            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CLSWindow.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1080, 1080};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {431, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CLSWindow = uifigure('Visible', 'off');
            app.CLSWindow.Name = 'CLS';
            app.CLSWindow.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.CLSWindow.Tag = 'CLSWindow';
            app.GridLayout = uigridlayout(app.CLSWindow);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.SubjectIDEditFieldLabel = uilabel(app.LeftPanel);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.LeftPanel, 'text');
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.StimulusBandwidthDropDownLabel = uilabel(app.LeftPanel);
            app.StimulusBandwidthDropDownLabel.Text = 'Stimulus Bandwidth';
            app.StimulusBandwidthDropDown = uidropdown(app.LeftPanel);
            app.StimulusBandwidthDropDown.Items = {'tone', '1/4 Oct', '1/2 Oct', '3/4 Oct', '1 Oct'};
            app.StimulusBandwidthDropDown.Value = 'tone';
            app.CalibrateButton = uibutton(app.LeftPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.TestEarDropDownLabel = uilabel(app.LeftPanel);
            app.TestEarDropDownLabel.Text = 'Test Ear';
            app.TestEarDropDown = uidropdown(app.LeftPanel);
            app.TestEarDropDown.Items = {'Left', 'Right', 'Both'};
            app.TestEarDropDown.Value = 'Left';
            app.StimulusTypeDropDownLabel = uilabel(app.LeftPanel);
            app.StimulusTypeDropDownLabel.Text = 'Stimulus Type';
            app.StimulusTypeDropDown = uidropdown(app.LeftPanel);
            app.StimulusTypeDropDown.Items = {'Noise', 'AM'};
            app.StimulusTypeDropDown.Value = 'AM';
            app.RightPanel = uipanel(app.GridLayout);
            app.CU50_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU50_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU50_pushbutton.Tag = 'CU50_pushbutton';
            app.CU50_pushbutton.Text = 'Too Loud';
            app.CU45_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU45_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU45_pushbutton.Tag = 'CU45_pushbutton';
            app.CU45_pushbutton.Text = 'Very Loud';
            app.CU40_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU40_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU40_pushbutton.Tag = 'CU40_pushbutton';
            app.CU40_pushbutton.Text = '';
            app.CU35_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU35_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU35_pushbutton.Tag = 'CU35_pushbutton';
            app.CU35_pushbutton.Text = 'Loud';
            app.CU30_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU30_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU30_pushbutton.Tag = 'CU30_pushbutton';
            app.CU30_pushbutton.Text = '';
            app.CU25_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU25_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU25_pushbutton.Tag = 'CU25_pushbutton';
            app.CU25_pushbutton.Text = 'Medium';
            app.CU20_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU20_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU20_pushbutton.Tag = 'CU20_pushbutton';
            app.CU20_pushbutton.Text = '';
            app.CU15_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU15_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU15_pushbutton.Tag = 'CU15_pushbutton';
            app.CU15_pushbutton.Text = 'Soft';
            app.CU10_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU10_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU10_pushbutton.Tag = 'CU10_pushbutton';
            app.CU10_pushbutton.Text = '';
            app.CU5_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU5_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU5_pushbutton.Tag = 'CU5_pushbutton';
            app.CU5_pushbutton.Text = 'Very Soft';
            app.CU0_pushbutton = uibutton(app.RightPanel, 'push');
            app.CU0_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @RespButton_Callback, true);
            app.CU0_pushbutton.Tag = 'CU0_pushbutton';
            app.CU0_pushbutton.Text = 'Can''t Hear';
            app.text2 = uilabel(app.RightPanel);
            app.text2.Tag = 'text2';
            app.text2.Text = '';
            app.subjectNotification = uibutton(app.RightPanel, 'push');
            app.subjectNotification.Text = 'Please Wait';
            % Show the figure after all components are created
            app.CLSWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qCLS_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CLSWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)CLS_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CLSWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLSWindow)
        end
    end
end